classdef volt_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VoltAppUIFigure            matlab.ui.Figure
        RunanalysisButton          matlab.ui.control.Button
        AddintervaltoignoreButton  matlab.ui.control.Button
        ROIsListBox                matlab.ui.control.ListBox
        ROIsListBoxLabel           matlab.ui.control.Label
        FolderpathEditField        matlab.ui.control.EditField
        FolderpathEditFieldLabel   matlab.ui.control.Label
        ResetButton                matlab.ui.control.Button
        LoadButton                 matlab.ui.control.Button
        FindfolderButton           matlab.ui.control.Button
        SpikesSwitch               matlab.ui.control.ToggleSwitch
        SaveanalysisButton         matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
        UIAxes_Fdet                matlab.ui.control.UIAxes
        UIAxes_DFF_AP              matlab.ui.control.UIAxes
        UIAxes_DFF_traces          matlab.ui.control.UIAxes
        UIAxes_SNR_AP              matlab.ui.control.UIAxes
        UIAxes_SNR_traces          matlab.ui.control.UIAxes
        UIAxes_image               matlab.ui.control.UIAxes
        UIAxes_Fraw                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)

        % TODO
        % show detrended F, SNR and DF/F trace and scatter plots (and d' scatter?) plots
        % add ROI toggle functionality to those plots
        % add spike toggle functionality to trace plots
        % save analyses and plots
        % dynamically added subaxes
        % show snippets, highpass data, and SNR/DFF traces with individual axes per ROI in other window
        % clear previous data with load button and with reset button
        % show k_valid range on raw F plot and limit other plots based on
            % it (and update when changed)
        % make multi-range time selection possible

        datadir   % directory of data
        titlestr  % name of data
        dat       % structure with original data
        F_proc    % structure for processed data
        plot_range % shading area for valid timepoints
        lines_raw % handles for lines in raw F plot
        
        H_lines_SNR
        H_lines_DFF
        H_lines_det

        lines_SNR
        scatter_SNR
        lines_DFF
        scatter_DFF
        spikes_SNR
        spikes_DFF
        lines_det
        spikes_det

        t_intervals = [];
        k_intervals = [];
    end
    
    methods (Access = private)
        
        function startupFcn(app)
            app.VoltAppUIFigure.Name = 'VoltApp';
            addpath(genpath('/net/engnas/Users/s/s/sshayk/My Documents/MATLAB/analyze_voltage'))
            addpath(genpath('\\ad\eng\users\s\s\sshayk\My Documents\MATLAB\analyze_voltage'))

            addpath '/net/engnas/Users/s/s/sshayk/My Documents/MATLAB/utilities'
            addpath '\\engnas.bu.edu\users\s\s\sshayk\My Documents\MATLAB\utilities'
            app.UIAxes_Fraw.Visible = 'off';
            app.UIAxes_image.Visible = 'off';
            app.ROIsListBox.Visible = 'off';
            app.AddintervaltoignoreButton.Visible  = 'off';
            
        end

        
        function [] = update_ROIS(app)
            
        end
        
        function [] = clear_data(app)
            app.startupFcn()
            
            app.ROIsListBox.Items = {};
            app.ROIsListBox.Value = {};
            cla([app.UIAxes_Fraw, app.UIAxes_image])
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: FolderpathEditField
        function FolderpathEditFieldValueChanged(app, event)
            app.datadir = app.FolderpathEditField.Value;
        end

        % Button pushed function: FindfolderButton
        function FindfolderButtonPushed(app, event)
            app.datadir = uigetdir;
            app.FolderpathEditField.Value = app.datadir;
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            datastr = strrep(app.datadir,'\\ad\eng\research\','U:\');

            strparts = strsplit(datastr,{'/','\'});
            app.titlestr = [strparts{end-2},'/',strparts{end-3},'/',strparts{end-1},'/',strparts{end}(10:end)];
            app.dat.signal = load(fullfile(datastr,'signal.mat'));
            app.dat.rois = load(fullfile(datastr,'rois.mat'));
            app.dat.nframes = size(app.dat.signal.tr,2);
            app.dat.params.fs = 800;
            app.dat.tvec = (1:app.dat.nframes)*(1/app.dat.params.fs);
            app.dat.params.k_valid = true(size(app.dat.tvec));

            % get F values
            app.F_proc = process_voltage(app.dat.signal.tr);
           
            NR = size(app.dat.rois.roimat,3);

            app.UIAxes_Fraw.Visible = 'on';
            hold(app.UIAxes_Fraw,'on')
            for nr = 1:NR
                app.lines_raw{nr} = plot(app.UIAxes_Fraw,app.dat.tvec,-app.dat.signal.tr(nr,:),'DisplayName',['ROI ',num2str(nr)]);
            end
            legend(app.UIAxes_Fraw)
            
            xlabel(app.UIAxes_Fraw,'time (s)'), ylabel(app.UIAxes_Fraw,'-F')
            title(app.UIAxes_Fraw,app.titlestr)
            legend(app.UIAxes_Fraw)
           
            for nr = 1:NR
                app.ROIsListBox.Items{nr} = ['ROI ', num2str(nr)];
            end
            app.ROIsListBox.Value = app.ROIsListBox.Items;
            app.ROIsListBox.Visible = 'on';

            app.dat.params.roi_use = 1:NR;

            imfile = [strparts{end}(10:end),'.raw'];
            reader = FrameReader(fullfile(strparts{1:end-1},imfile));

            load(fullfile(datastr,'mask.mat'))
            load(fullfile(datastr,'rois.mat'))
            load(fullfile(datastr,'translation.mat'))
    
            im = double(reader.getFrames(100));
    
            translation = gather(translation);
            for k = 1:size(im,3)
                im_t(:,:,k) = circshift(im(:,:,k), translation(k,:));
            end
            im_av = mean(im_t,3);
        
            cents = zeros(2,NR);
            for nr = 1:NR
                cents(:,nr) = gray_centroid(roimat(:,:,nr));
            end
    
            imagesc(app.UIAxes_image,im_av-100)
            axis(app.UIAxes_image,'image') 
            colormap(app.UIAxes_image,'gray')
            
            for nr = 1:NR
                text(app.UIAxes_image,cents(2,nr), cents(1,nr)-30,num2str(nr),'Color','y')
            end
    
            app.UIAxes_image.Visible = 'on';
            app.AddintervaltoignoreButton.Visible  = 'on';


        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.clear_data();
        end

        % Value changed function: ROIsListBox
        function ROIsListBoxValueChanged(app, event)


            k_r = ismember(app.ROIsListBox.Items, app.ROIsListBox.Value);
            
            NR = length(app.ROIsListBox.Items);
            for nr = 1:NR
                if k_r(nr)
                     app.lines_raw{nr}.HandleVisibility = 'on';
                     app.lines_raw{nr}.Visible = 'on';
                     app.lines_DFF{nr}.Visible = 'on';
                     app.lines_SNR{nr}.Visible = 'on';
                     app.scatter_DFF{nr}.Visible = 'on';
                     app.scatter_SNR{nr}.Visible = 'on';
                     app.lines_det{nr}.Visible = 'on';
                     % app.spikes_DFF.Visible = app.SpikesSwitch.Value;
                     % app.spikes_SNR.Visible = app.SpikesSwitch.Value;
                     % app.spikes_det.Visible = app.SpikesSwitch.Value;
                 else
                     app.lines_raw{nr}.HandleVisibility = 'off';
                     app.lines_raw{nr}.Visible = 'off';
                     app.lines_DFF{nr}.Visible = 'off';
                     app.lines_SNR{nr}.Visible = 'off';
                     app.scatter_DFF{nr}.Visible = 'off';
                     app.scatter_SNR{nr}.Visible = 'off';
                     app.lines_det{nr}.Visible = 'off';
                     % app.spikes_DFF.Visible = 'off';
                     % app.spikes_SNR.Visible = 'off';
                     % app.spikes_det.Visible = 'off';
                end
            end
        end

        % Button pushed function: AddintervaltoignoreButton
        function AddintervaltoignoreButtonPushed(app, event)
            newintervals = selectIntervals(app.UIAxes_Fraw);
            app.t_intervals = [app.t_intervals;newintervals];
            
        end

        % Button pushed function: RunanalysisButton
        function RunanalysisButtonPushed(app, event)
            tvec = app.dat.tvec;

            for nk = 1:size(app.t_intervals,1)
                cur_int = app.t_intervals(nk,:);
                app.dat.params.k_valid(tvec<=max(cur_int) & tvec>=min(cur_int)) = 0;
            end

             NR = size(app.dat.rois.roimat,3);

            [app.F_proc.F_AP, app.F_proc.params_AP] = calc_APs(app.F_proc.F_det);
            % get spikes
            [app.F_proc.t_s, app.F_proc.parameters_kspikes] = get_spike_locations(app.F_proc.F_det, app.F_proc.F_AP, app.F_proc.N_f, app.F_proc.dF_ur, app.F_proc.dF_dr); 
            % get Vm and other baseline calculations
            [app.F_proc.F_sub, app.F_proc.F_nospike, app.F_proc.F_0, app.F_proc.params_baselines] = get_subthreshold_trace(app.dat.signal.tr, app.F_proc.t_s);


            app.dat.snr_trace = app.F_proc.F_det./app.F_proc.N_f;
            app.dat.snr_AP = app.F_proc.F_AP./app.F_proc.N_f; % only valid at detected spikes

            app.dat.dff_trace = -(app.dat.signal.tr-app.F_proc.F_0)./app.F_proc.F_0;
            app.dat.dff_AP = app.F_proc.F_AP./app.F_proc.F_0; % only valid at detected spikes


            hold(app.UIAxes_SNR_AP,'on');
            for nr = 1:NR
                if nnz(app.F_proc.t_s(nr,app.dat.params.k_valid))
                    app.scatter_SNR{nr} = scatter(app.UIAxes_SNR_AP,nr*ones(1,nnz(app.F_proc.t_s(nr,app.dat.params.k_valid))), app.dat.snr_AP(nr,app.F_proc.t_s(nr,app.dat.params.k_valid)),30,'filled','k');
                end
            end

            hold(app.UIAxes_DFF_AP,'on');
            for nr = 1:NR
                if nnz(app.F_proc.t_s(nr,app.dat.params.k_valid))
                    app.scatter_DFF{nr} = scatter(app.UIAxes_DFF_AP,nr*ones(1,nnz(app.F_proc.t_s(nr,app.dat.params.k_valid))), app.dat.dff_AP(nr,app.F_proc.t_s(nr,app.dat.params.k_valid)),30,'filled','k');
                end
            end

           hold(app.UIAxes_SNR_traces,'on');
           for nr = 1:NR
            app.lines_SNR{nr} = plot(app.UIAxes_SNR_traces,app.dat.tvec(app.dat.params.k_valid),app.dat.snr_trace(nr,app.dat.params.k_valid,1),'DisplayName',['ROI ', num2str(nr)]);
           end


           hold(app.UIAxes_DFF_traces,'on');
           for nr = 1:NR
            app.lines_DFF{nr} = plot(app.UIAxes_DFF_traces,app.dat.tvec(app.dat.params.k_valid),app.dat.dff_trace(nr,app.dat.params.k_valid,1),'DisplayName',['ROI ', num2str(nr)]);
           end

           hold(app.UIAxes_Fdet,'on');
           for nr = 1:NR
            app.lines_det{nr} = plot(app.UIAxes_Fdet,app.dat.tvec(app.dat.params.k_valid),app.F_proc.F_det(nr,app.dat.params.k_valid),'DisplayName',['ROI ', num2str(nr)]);
           end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.VoltAppUIFigure = uifigure('Visible', 'off');
            app.VoltAppUIFigure.Name = 'VoltApp';
            app.UIAxes_Fraw = uiaxes(app.VoltAppUIFigure);
            title(app.UIAxes_Fraw, 'Raw F traces')
            xlabel(app.UIAxes_Fraw, 'time (s)')
            ylabel(app.UIAxes_Fraw, '-F')
            zlabel(app.UIAxes_Fraw, 'Z')
            app.UIAxes_Fraw.Visible = 'off';
            app.UIAxes_image = uiaxes(app.VoltAppUIFigure);
            zlabel(app.UIAxes_image, 'Z')
            app.UIAxes_image.Visible = 'off';
            app.UIAxes_SNR_traces = uiaxes(app.VoltAppUIFigure);
            xlabel(app.UIAxes_SNR_traces, 'time (s)')
            ylabel(app.UIAxes_SNR_traces, 'SNR')
            zlabel(app.UIAxes_SNR_traces, 'Z')
            app.UIAxes_SNR_AP = uiaxes(app.VoltAppUIFigure);
            xlabel(app.UIAxes_SNR_AP, 'ROI')
            ylabel(app.UIAxes_SNR_AP, 'SNR')
            zlabel(app.UIAxes_SNR_AP, 'Z')
            app.UIAxes_DFF_traces = uiaxes(app.VoltAppUIFigure);
            xlabel(app.UIAxes_DFF_traces, 'time (s)')
            ylabel(app.UIAxes_DFF_traces, '\DeltaF/F')
            zlabel(app.UIAxes_DFF_traces, 'Z')
            app.UIAxes_DFF_AP = uiaxes(app.VoltAppUIFigure);
            xlabel(app.UIAxes_DFF_AP, 'ROI')
            ylabel(app.UIAxes_DFF_AP, '\DeltaF/F')
            zlabel(app.UIAxes_DFF_AP, 'Z')
            app.UIAxes_Fdet = uiaxes(app.VoltAppUIFigure);
            title(app.UIAxes_Fdet, 'detrended F')
            xlabel(app.UIAxes_Fdet, 'time (s)')
            ylabel(app.UIAxes_Fdet, 'F')
            zlabel(app.UIAxes_Fdet, 'Z')
            app.UIAxes = uiaxes(app.VoltAppUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SaveanalysisButton = uibutton(app.VoltAppUIFigure, 'push');
            app.SaveanalysisButton.Text = 'Save analysis';
            app.SpikesSwitch = uiswitch(app.VoltAppUIFigure, 'toggle');
            app.SpikesSwitch.Items = {'', 'Spikes on '};
            app.SpikesSwitch.Value = '';
            app.FindfolderButton = uibutton(app.VoltAppUIFigure, 'push');
            app.FindfolderButton.ButtonPushedFcn = createCallbackFcn(app, @FindfolderButtonPushed, true);
            app.FindfolderButton.Text = 'Find folder';
            app.LoadButton = uibutton(app.VoltAppUIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.ResetButton = uibutton(app.VoltAppUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.FolderpathEditFieldLabel = uilabel(app.VoltAppUIFigure);
            app.FolderpathEditFieldLabel.Text = 'Folder path';
            app.FolderpathEditField = uieditfield(app.VoltAppUIFigure, 'text');
            app.FolderpathEditField.ValueChangedFcn = createCallbackFcn(app, @FolderpathEditFieldValueChanged, true);
            app.ROIsListBoxLabel = uilabel(app.VoltAppUIFigure);
            app.ROIsListBoxLabel.Visible = 'off';
            app.ROIsListBoxLabel.Text = 'ROIs';
            app.ROIsListBox = uilistbox(app.VoltAppUIFigure);
            app.ROIsListBox.Multiselect = 'on';
            app.ROIsListBox.ValueChangedFcn = createCallbackFcn(app, @ROIsListBoxValueChanged, true);
            app.ROIsListBox.Visible = 'off';
            app.ROIsListBox.Value = {'Item 1'};
            app.AddintervaltoignoreButton = uibutton(app.VoltAppUIFigure, 'push');
            app.AddintervaltoignoreButton.ButtonPushedFcn = createCallbackFcn(app, @AddintervaltoignoreButtonPushed, true);
            app.AddintervaltoignoreButton.Visible = 'off';
            app.AddintervaltoignoreButton.Text = 'Add interval to ignore';
            app.RunanalysisButton = uibutton(app.VoltAppUIFigure, 'push');
            app.RunanalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RunanalysisButtonPushed, true);
            app.RunanalysisButton.Text = 'Run analysis';
            % Show the figure after all components are created
            app.VoltAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = volt_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VoltAppUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VoltAppUIFigure)
        end
    end
end